classdef SpeckleTrackingOptimizedHighSpec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        CreateDisplacementVideoButton  matlab.ui.control.Button
        AnalyzeImagesButton            matlab.ui.control.Button
        LoadImagesButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        FileLocation
        FolderLocation
        FolderInfo
        Images
        
        size1
        size2
        size3
        size4
        size5
        
        WindowSize = 50;
        HalfWindowSize
        MvtLimit = 10;
    end
    
    methods (Access = public)
        function VecFieldComp = TrackParticles(~, I1, I2, WindowSize, MvtLimit)
            I1 = adapthisteq(I1);
            I2 = adapthisteq(I2);
            
            I1 = double(I1) - mean(I1, 'all');
            I2 = double(I2) - mean(I2, 'all');
            
            ImageSize1 = size(I1, 1);
            ImageSize2 = size(I1, 2);

            SectionAmt1 = floor(ImageSize1 / WindowSize); %might ignore a few pixles but...
            SectionAmt2 = floor(ImageSize2 / WindowSize);

            DispVecComp = zeros(SectionAmt1, SectionAmt2, 2);

            for i = 2:SectionAmt1-1
                for j = 2:SectionAmt2-1
%                     try 
                    Window1 = I1((i - 1) * WindowSize + 1 : i * WindowSize, (j - 1) * WindowSize + 1 : j * WindowSize);
                    Window2 = I2((i - 1) * WindowSize + 1 - MvtLimit : i * WindowSize + MvtLimit, (j - 1) * WindowSize + 1 - MvtLimit : j * WindowSize + MvtLimit);
                    CrossCorrelation = xcorr2(gpuArray(Window1), gpuArray(Window2));

                    if sum(CrossCorrelation == max(CrossCorrelation, [], 'all'), "all") == 1
                        [a, b] = find(CrossCorrelation == max(CrossCorrelation, [], 'all'));
                        DispVecComp(i, j, 1) = WindowSize + MvtLimit - a;
                        DispVecComp(i, j, 2) = WindowSize + MvtLimit - b;
                    else
                        DispVecComp(i, j, 1) = 0;
                        DispVecComp(i, j, 2) = 0;
                    end

%                     catch % for edge cases
%                         disp("Error 1")
%                         continue
%                         %code edge cases later 
%                     end
                end
            end    

            VecFieldComp =  cat(3, medfilt2(DispVecComp(:,:,1)), medfilt2(DispVecComp(:,:,2)));
        end
        
%         function z = f(~, x, y) %for finding window min boundary
%             z = (x - 1) * y + 1;
%         end
% 
%         function z = g(~, x, y) %for finding window max boundary
%             z = x * y;
%         end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
%             try
            msgbox("Please select the image folder.", "Loading Images");
            pause(0.5)
            app.FileLocation = uigetdir("C:");
            %app.FileLocation = "C:\Users\Justin\Justin Documents\IEM OPALS\Speckle Tracking\10x Image Set";
            
            app.FolderInfo = dir(app.FileLocation + "\*.tif");

            [app.size1, app.size2] = size(rgb2gray(imread(app.FileLocation + "\" + app.FolderInfo(1).name)));            
            app.size3 = size(app.FolderInfo, 1);
            
            
            TempImages = uint8(zeros(app.size1, app.size2, app.size3));
            
            for I = 1 : app.size3
                TempImages(:,:,I) = rgb2gray(imread(app.FileLocation + "\" + app.FolderInfo(I).name));
                
                msgbox("Loading Images... " + I + "/" + app.size3, "Loading Images", "replace");
            end
     
            if app.FileLocation == "-1" 
                msgbox("Error selecting the images.", "Loading Images", "modal");
            else
                msgbox("Successfully selected the images.", "Loading Images", "modal");
            end
            
            app.Images = TempImages;
            clear TempImages;
            
            
            app.HalfWindowSize = ceil(app.WindowSize/2); %dont worry about why this is here :skull:
            app.size4 = floor(app.size1 / app.WindowSize);
            app.size5 = floor(app.size2 / app.WindowSize);

            
%             catch
%                 msgbox("Error selecting the folders.")
%             end
        end

        % Button pushed function: AnalyzeImagesButton
        function AnalyzeImagesButtonPushed(app, event)
            msgbox("Analyzing Images...", "Analyzing Images");
            
            DispVecField = zeros(floor(app.size1 / app.WindowSize), floor(app.size2 / app.WindowSize), 2, app.size3 - 1);
            
            for I = 1 : app.size3 - 1
                DispVecField(:, :, :, I) = app.TrackParticles(app.Images(:, :, I), app.Images(:, :, I + 1), app.WindowSize, app.MvtLimit);
%                 writematrix(DispVecField(:, :, 1), "Vector Field X Components.xls", 'Sheet', I);
%                 writematrix(DispVecField(:, :, 2), "Vector Field Y Components.xls", 'Sheet', I);
                
                msgbox("Analyzing Images " + I + "/" + (app.size3-1), "Analyzing Images", "replace");
            end
            
            msgbox("Done Analyzing Images", "Analyzing Images", "modal");
            
            save("VecFieldComp.mat", 'DispVecField');
        end

        % Button pushed function: CreateDisplacementVideoButton
        function CreateDisplacementVideoButtonPushed(app, event)
            msgbox("Creating Video...", "Creating Video");
            
            load('VecFieldComp.mat');
            
            v = VideoWriter("VectorsField.avi");
            v.FrameRate = 110;
            open(v);
            
            a = repelem((1:floor(app.size1/app.WindowSize))*app.WindowSize + app.HalfWindowSize, floor(app.size2/app.WindowSize), 1);
            b = repelem(((1:floor(app.size2/app.WindowSize))')*app.WindowSize + app.HalfWindowSize, 1, floor(app.size2/app.WindowSize));
            
            f = figure("Name", "Vector Field");
            
            for i = 1 : app.size3 - 1
                figure(f)
                imshow(app.Images(:, :, i)); 
                
                hold on;
                
                
                quiver(a, b, DispVecField(:, :, 2, i), DispVecField(:, :, 1, i), "color", "w", "LineWidth",2); %Vectors(i*floor(Size1/WindowSize) + j) =

                frame = frame2im(getframe());
                writeVideo(v, frame)
                
                hold off;
                clf(f)
                
                msgbox("Creating Video " + i + "/" + (app.size3 - 1), "Creating Video", "replace");
            end
            
            close(v)
            close(f)
            
            msgbox("Done Creating Video", "Creating Video", "modal");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.AnalyzeImagesButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeImagesButtonPushed, true);
            app.AnalyzeImagesButton.Text = 'Analyze Images';
            app.CreateDisplacementVideoButton = uibutton(app.UIFigure, 'push');
            app.CreateDisplacementVideoButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDisplacementVideoButtonPushed, true);
            app.CreateDisplacementVideoButton.Text = 'Create Displacement Video';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'1. Load in Images (Select the folder with the stack of images)'; '2. Analyze Images (creates a .mat file of the vector field components)'; '3.  a) Create Displacement Vector Field Video (creates video from .mat file of vector filed components)'; '     b) Create Strain Field Video (from Displacement Vector Field)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpeckleTrackingOptimizedHighSpec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end